classdef thermo_sim_sketchv9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        Label2                       matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        switchtouserinputButton      matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        Tab2                         matlab.ui.container.Tab
        Gauge_2                      matlab.ui.control.LinearGauge
        SavefiguresButton            matlab.ui.control.Button
        ExportCSVButton              matlab.ui.control.Button
        HoldpreviousrunsCheckBox     matlab.ui.control.CheckBox
        HeaterKnob                   matlab.ui.control.Knob
        HeaterKnobLabel              matlab.ui.control.Label
        ThermostattemperatureGauge   matlab.ui.control.SemicircularGauge
        GaugeLabel                   matlab.ui.control.Label
        DesiredTemperatureEditField  matlab.ui.control.NumericEditField
        DesiredroomtemperatureLabel  matlab.ui.control.Label
        ThermostatSwitch             matlab.ui.control.ToggleSwitch
        ThermostatSwitchLabel        matlab.ui.control.Label
        stopsimulationButton         matlab.ui.control.Button
        RrFieldEditField             matlab.ui.control.NumericEditField
        RrFieldEditFieldLabel        matlab.ui.control.Label
        C2FieldEditField             matlab.ui.control.NumericEditField
        C2FieldEditFieldLabel        matlab.ui.control.Label
        C1FieldEditField             matlab.ui.control.NumericEditField
        C1FieldEditFieldLabel        matlab.ui.control.Label
        RwFieldEditField             matlab.ui.control.NumericEditField
        RwFieldEditFieldLabel        matlab.ui.control.Label
        RunSimulationButton          matlab.ui.control.Button
        RoomAxes                     matlab.ui.control.UIAxes
        HeatFlowAxes                 matlab.ui.control.UIAxes
        TemperatureAxes              matlab.ui.control.UIAxes
        Tab4                         matlab.ui.container.Tab
        ToProfileDropDown            matlab.ui.control.DropDown
        OutsideToDropDownLabel       matlab.ui.control.Label
        QProfileDropDown             matlab.ui.control.DropDown
        HeaterQDropDownLabel         matlab.ui.control.Label
        QFieldEditField              matlab.ui.control.NumericEditField
        QFieldEditFieldLabel         matlab.ui.control.Label
        TendFieldEditField           matlab.ui.control.NumericEditField
        TendFieldEditFieldLabel      matlab.ui.control.Label
        Tw0FieldEditField            matlab.ui.control.NumericEditField
        Tw0FieldEditFieldLabel       matlab.ui.control.Label
        Tr0FieldEditField            matlab.ui.control.NumericEditField
        Tr0FieldEditFieldLabel       matlab.ui.control.Label
        ToFieldEditField             matlab.ui.control.NumericEditField
        ToFieldEditFieldLabel        matlab.ui.control.Label
        InputAxes                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        RunHistory = struct('C1', {},'C2',{}, 'Rr', {},'Rw',{},'Qprofile', {},'Toprofile',{}, 't', {},'Tr',{},'Tw', {},'To',{}, 'Q', {});
    end


    methods (Access = private)

    function out = ThermalModelTV(~, C1, C2, Rr, Rw, ...
            Tr0, Tw0, Tend, Qcfg, Tocfg, Tset, manualMode)

        %% 1) Check model parameters
        if any(~isfinite([C1 C2 Rr Rw Tr0 Tw0 Tend Tset])) || ...
                any([C1 C2 Rr Rw Tend] <= 0)
            error('Use finite inputs and positive capacities, resistances and duration.');
        end

        %% 2) State-space model
        A = [-1/(Rr*C1),  1/(Rr*C1);
              1/(Rr*C2), -(1/Rr + 1/Rw)/C2];

        B = [1/C1, 0;
             0,    1/(Rw*C2)];

        plant = ss(A, B, eye(2), zeros(2));

        plant.InputName  = {'Q', 'To'};
        plant.OutputName = {'Tr', 'Tw'};
        plant.StateName  = {'RoomTemperature', 'WallTemperature'};

        t = linspace(0, Tend, 3001)';

        %% 3) Generate input profiles
        % Automatic mode only needs the outside-temperature profile.
        configs = {Qcfg, Tocfg};
        signals = zeros(numel(t), 2);
        nominals = zeros(1, 2);

        for j = 1:2

            if j == 1 && ~manualMode
                continue
            end

            p = configs{j};

            switch lower(string(p.Type))
                case "constant"
                    u = p.Level * ones(size(t));
                    nominal = p.Level;

                case "step"
                    u = p.Level * ones(size(t));
                    u(t >= p.TStep) = p.Level2;
                    nominal = p.Level2;

                case {"sinusoidal", "daily sinusoid"}
                    if ~isfinite(p.Period) || p.Period <= 0
                        error('Sinusoidal period must be positive and finite.');
                    end

                    u = p.Mean + p.Amp * sin(2*pi*t/p.Period);
                    nominal = p.Mean;

                case {"random", "random around mean"}
                    if ~isfinite(p.Hold) || p.Hold <= 0
                        error('Random hold time must be positive and finite.');
                    end

                    stream = RandStream('mt19937ar', 'Seed', p.Seed);

                    idx = floor(t/p.Hold) + 1;
                    vals = p.Mean + p.Amp * ...
                        randn(stream, max(idx), 1);

                    u = vals(idx);
                    nominal = p.Mean;

                otherwise
                    error('Unknown profile type "%s".', string(p.Type));
            end

            if any(~isfinite(u))
                error('Input profiles must contain finite values.');
            end

            signals(:,j) = u;
            nominals(j) = nominal;
        end

        To = signals(:,2);
        Tonom = nominals(2);

        %% 4) Choose manual or automatic simulation
        if manualMode

            % Manual heater power from Qcfg
            Q = min(max(signals(:,1), 0), 10);
            Qnom = min(max(nominals(1), 0), 10);

            y = lsim(plant, [Q To], t, [Tr0; Tw0]);

            out.Tr = y(:,1);
            out.Tw = y(:,2);
            out.Q  = Q;

            % Reference equilibrium for nominal inputs
            out.Tr_ss = Tonom + Qnom*(Rr + Rw);
            out.Tw_ss = Tonom + Qnom*Rw;
            out.Q_ss  = Qnom;

            out.lambda = sort(eig(A), 'descend');
            out.tau = -1./ out.lambda;

            % No PID controller in manual mode
            out.Kp = NaN;
            out.Ki = NaN;
            out.Kd = NaN;
            out.Tf = NaN;

        else

            % Tune heater power -> room temperature
            controller = pidtune(plant(1,1), 'PIDF');

            controller.InputName  = 'error';
            controller.OutputName = 'Q';

            errorBlock = sumblk('error = Tset - Tr');

            % Preserve states for initial-temperature assignment
            options = connectOptions('Simplify', false);

            closedLoop = connect(plant, controller, errorBlock, ...
                {'Tset', 'To'}, {'Tr', 'Tw', 'Q'}, options);

            % Room/wall initial temperatures; controller states start at zero
            x0 = zeros(size(closedLoop.A,1), 1);

            roomIdx = strcmp(closedLoop.StateName, 'RoomTemperature');
            wallIdx = strcmp(closedLoop.StateName, 'WallTemperature');

            if nnz(roomIdx) ~= 1 || nnz(wallIdx) ~= 1
                error('Could not identify the room and wall states.');
            end

            x0(roomIdx) = Tr0;
            x0(wallIdx) = Tw0;

            TsetProfile = Tset * ones(size(t));

            y = lsim(closedLoop, [TsetProfile To], t, x0);

            out.Tr = y(:,1);
            out.Tw = y(:,2);
            out.Q  = y(:,3);

            out.Kp = controller.Kp;
            out.Ki = controller.Ki;
            out.Kd = controller.Kd;
            out.Tf = controller.Tf;

            % Reference equilibrium assuming stable target tracking
            out.Tr_ss = Tset;
            out.Q_ss  = (Tset - Tonom)/(Rr + Rw);
            out.Tw_ss = Tonom + out.Q_ss*Rw;

            out.lambda = pole(closedLoop);

            % Sort poles: slowest decay first
            [~, idx] = sort(real(out.lambda), 'descend');
            out.lambda = out.lambda(idx);

            % Real decay time constants for stable poles
            out.tau = NaN(size(out.lambda));
            stable = real(out.lambda) < 0;
            out.tau(stable) = -1 ./ real(out.lambda(stable));
            out.tau = unique(out.tau);
        end

        %% 5) Common outputs
        out.t = t;
        out.To = To;
        out.Tset = Tset;
        out.manualMode = manualMode;

    end
    
    function T = buildResultsTable(app)
        % One table: one block of rows per run, parameters on every row
        parts = cell(numel(app.RunHistory), 1);
        for k = 1:numel(app.RunHistory)
            r = app.RunHistory(k);
            n = numel(r.t);
            parts{k} = table( ...
                repmat(k, n, 1), ...
                repmat(r.C1, n, 1), repmat(r.C2, n, 1), ...
                repmat(r.Rr, n, 1), repmat(r.Rw, n, 1), ...
                repmat(string(r.Qprofile), n, 1), ...
                repmat(string(r.Toprofile), n, 1), ...
                r.t, r.Tr, r.Tw, r.To, r.Q, ...
                'VariableNames', {'Run', 'C1', 'C2', 'Rr', 'Rw', ...
                'Q_profile', 'To_profile', 'Time_s', ...
                'T_room_degC', 'T_wall_degC', 'T_outside_degC', 'Q_heater'});
        end
        T = vertcat(parts{:});
    end

    function setExportEnabled(app, onOff)
        app.ExportCSVButton.Enable   = onOff;
        app.SavefiguresButton.Enable = onOff;
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HeaterKnob.Enable = 'on';
            app.ToProfileDropDown.Enable = 'on';
            app.QProfileDropDown.Enable = 'on';
            app.QFieldEditField.Enable = 'on';
            app.ThermostatSwitch.Value = 'Off';
            app.DesiredTemperatureEditField.Enable = 'off';
            setExportEnabled(app, 'off');   % nothing to export before the first run
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
             try
                % Read inputs from the app
                C1 = app.C1FieldEditField.Value;
                C2 = app.C2FieldEditField.Value;
                Rr = app.RrFieldEditField.Value;
                Rw = app.RwFieldEditField.Value;
                Q  = app.QFieldEditField.Value;
                To = app.ToFieldEditField.Value;

                Tr0  = app.Tr0FieldEditField.Value;
                Tw0  = app.Tw0FieldEditField.Value;
                Tend = app.TendFieldEditField.Value;

                % Check inputs
                if any([C1, C2, Rr, Rw, Tend] <= 0)
                    uialert(app.UIFigure, ...
                        'Heat capacities, resistances and simulation time must be positive.', ...
                        'Invalid input');
                    return
                end

                if Q < 0
                    uialert(app.UIFigure, ...
                        'Heater input must be zero or positive.', ...
                        'Invalid input');
                    return
                end

                % Run the model
                Qcfg.Type   = app.QProfileDropDown.Value;
                Qcfg.Level  = Q;       Qcfg.Mean   = Q;
                Qcfg.Level2 = Q + 3;   Qcfg.TStep  = Tend/3;    % step
                Qcfg.Amp    = 2;       Qcfg.Period = 10;        % sinusoidal / random
                Qcfg.Hold   = 1;       Qcfg.Seed   = 1;         % random

                Tocfg.Type   = app.ToProfileDropDown.Value;
                Tocfg.Level  = To;     Tocfg.Mean  = To;
                Tocfg.Amp    = 2;      Tocfg.Period = 24;
                Tocfg.Hold   = 1;      Tocfg.Seed  = 2;

                % out = ThermalModelTV(app, C1, C2, Rr, Rw, Tr0, Tw0, Tend, Qcfg, Tocfg);
                manualMode = strcmp(app.ThermostatSwitch.Value, 'Off');
                Tset = app.DesiredTemperatureEditField.Value;


                if manualMode
                        Qcfg.Level = app.HeaterKnob.Value;
                end

                out = app.ThermalModelTV(C1, C2, Rr, Rw, ...
                    Tr0, Tw0, Tend, Qcfg, Tocfg, Tset, manualMode);
                %% Prepare simulation results (force column vectors)
                t  = out.t(:);
                Tr = out.Tr(:);
                Tw = out.Tw(:);

                % Use the time-varying inputs the model actually used
                % (ThermalModelTV returns them as vectors on the same grid as t).
                % v4 used the scalar To and Q*ones(...), which is wrong for
                % every profile except 'Constant'. out.Q is already clipped
                % at 0, so the export matches what was simulated.
                To_t = out.To(:);
                Q_t  = out.Q(:);

                Q_heat = Q_t;
                Q_rw   = (Tr - Tw) / Rr;
                Q_wo   = (Tw - To_t) / Rw;
                %% Store this run (used by hold + exports)
                thisRun = struct('C1', C1, 'C2', C2, 'Rr', Rr, 'Rw', Rw, ...
                    'Qprofile', Qcfg.Type, 'Toprofile', Tocfg.Type, ...
                    't', t, 'Tr', Tr, 'Tw', Tw, 'To', To_t, 'Q', Q_t);

                holdOn = app.HoldpreviousrunsCheckBox.Value;
                if holdOn
                    app.RunHistory(end+1) = thisRun;
                else
                    app.RunHistory = thisRun;
                end
                runNo = numel(app.RunHistory);
                % setExportEnabled(app, 'on');

                % Colour range for the room diagram (current run only)
                allTemps = [Tr; Tw; To_t; out.Tr_ss; out.Tw_ss];
                Tmin = min(allTemps) - 1;
                Tmax = max(allTemps) + 1;

                %% Use the axes already in the app
                ax1 = app.RoomAxes;
                ax2 = app.TemperatureAxes;
                ax3 = app.HeatFlowAxes;

                cla(ax1);
                cla(ax3);

                % Only clear the temperature plot when not holding.
                % When holding, delete the previous run's annotations
                % (steady-state lines, tau lines, time marker, outside
                % temperature) but keep its temperature curves.
                if holdOn
                    delete(findobj(ax2, 'Tag', 'annot'));
                else
                    cla(ax2);
                end

                %% 1) Room diagram
                hold(ax1, 'on');

                zones = {[0 1], [1 2], [2 5]};
                zoneNames = {'Outside', 'Wall', 'Room'};

                hP = gobjects(1, 3);
                hT = gobjects(1, 3);

                for k = 1:3
                    left  = zones{k}(1);
                    right = zones{k}(2);

                    hP(k) = patch(ax1, ...
                        [left right right left], [0 0 3 3], To_t(1), ...
                        'FaceColor', 'flat', ...
                        'EdgeColor', 'k', 'LineWidth', 1.5);

                    text(ax1, mean(zones{k}), 2.75, zoneNames{k}, ...
                        'HorizontalAlignment', 'center', ...
                        'FontWeight', 'bold');

                    hT(k) = text(ax1, mean(zones{k}), 1.8, '', ...
                        'HorizontalAlignment', 'center', ...
                        'FontSize', 12, 'FontWeight', 'bold', ...
                        'BackgroundColor', [1 1 1]);
                end

                % Heater
                patch(ax1, [4.6 4.9 4.9 4.6], [0.3 0.3 1.3 1.3], ...
                    [0.4 0.4 0.4]);
                text(ax1, 4.75, 1.45, 'Heater', ...
                    'HorizontalAlignment', 'center', 'FontSize', 9);

                colormap(ax1, turbo);
                clim(ax1, [Tmin Tmax]);

                cb = colorbar(ax1);
                cb.Label.String = 'Temperature [°C]';

                axis(ax1, 'equal');
                axis(ax1, 'off');
                xlim(ax1, [-0.1 5.1]);
                ylim(ax1, [-0.1 3.1]);

                hTitle = title(ax1, '');
                hold(ax1, 'off');

                %% 2) Temperature curves 
                hold(ax2, 'on');

                % One colour per run; solid = room, dashed = wall
                cols = lines(7);
                c = cols(mod(runNo - 1, 7) + 1, :);

                runLabel = sprintf('Run %d: C_1=%g, C_2=%g, R_r=%g, R_w=%g, Q=%g', ...
                    runNo, C1, C2, Rr, Rw, Q);

                plot(ax2, t, Tr, '-', 'Color', c, 'LineWidth', 2, ...
                    'Tag', 'runTr', 'DisplayName', runLabel);
                plot(ax2, t, Tw, '--', 'Color', c, 'LineWidth', 1.5, ...
                    'Tag', 'runTw');

                % Annotations for the CURRENT run only (all tagged 'annot')
                hOut = plot(ax2, t, To_t, 'k-.', 'LineWidth', 1, ...
                    'Tag', 'annot', 'DisplayName', 'T_o (current run)');

                yline(ax2, out.Tr_ss, ':', ...
                    sprintf('T_{r,ss} = %.2f °C', out.Tr_ss), ...
                    'Color', c, 'Tag', 'annot');
                yline(ax2, out.Tw_ss, ':', ...
                    sprintf('T_{w,ss} = %.2f °C', out.Tw_ss), ...
                    'Color', c, 'Tag', 'annot', ...
                    'LabelVerticalAlignment', 'bottom');

                xline(ax2, out.tau(1), '--', ...
                    sprintf('\\tau_1 = %.2f', out.tau(1)), ...
                    'Color', [0.4 0.4 0.4], 'Tag', 'annot');
                xline(ax2, out.tau(2), '--', ...
                    sprintf('\\tau_2 = %.2f', out.tau(2)), ...
                    'Color', [0.4 0.4 0.4], 'Tag', 'annot');

                hM2 = xline(ax2, t(1), 'k', 'LineWidth', 1.5, 'Tag', 'annot');

                % Legend: one entry per run (oldest first) + outside temperature.
                % findobj returns newest first, hence flipud.
                hRuns = flipud(findobj(ax2, 'Tag', 'runTr'));
                lg = legend(ax2, [hRuns; hOut]);
                lg.Location   = 'southeast';  % keep the legend off the curves
                lg.AutoUpdate = 'off';          % later objects don't join the legend
                lg.Title.String = 'solid: T_r   dashed: T_w';

                xlabel(ax2, 'Time [s]');
                ylabel(ax2, 'Temperature [°C]');
                if holdOn && runNo > 1
                    title(ax2, sprintf('Temperature curves (%d runs held)', runNo));
                else
                    title(ax2, 'Temperature curves');
                end
                tMax = max(arrayfun(@(r) r.t(end), app.RunHistory));
                xlim(ax2, [0 tMax]);
                grid(ax2, 'on');
                hold(ax2, 'off');

                %% 3) Heat-flow curves (always current run only)
                hold(ax3, 'on');

                hHeat = plot(ax3, t, Q_heat, ...
                    'Color', [0.85 0.33 0.1], 'LineWidth', 2);
                hRW = plot(ax3, t, Q_rw, ...
                    'Color', [0.5 0 0.5], 'LineWidth', 2);
                hWO = plot(ax3, t, Q_wo, ...
                    'Color', [0 0.45 0.74], 'LineWidth', 2);

                hM3 = xline(ax3, t(1), 'k', 'LineWidth', 1.5);

                legend(ax3, [hHeat hRW hWO], ...
                    {'Heater to room', 'Room to wall', 'Wall to outside'}, ...
                    'Location', 'best', 'AutoUpdate', 'off');

                xlabel(ax3, 'Time [s]');
                ylabel(ax3, 'Heat flow [model units]');
                title(ax3, 'Heat flows');
                xlim(ax3, [t(1) t(end)]);
                grid(ax3, 'on');
                hold(ax3, 'off');

                %% 3b) Input profiles Q(t) and To(t) (current run only)
                ax4 = app.InputAxes;
                % With two y-axes, cla only clears the active side: clear both
                yyaxis(ax4, 'left');  cla(ax4);
                yyaxis(ax4, 'right'); cla(ax4);

                yyaxis(ax4, 'left');
                hQ = plot(ax4, t, Q_t, '-', 'LineWidth', 2);
                ylabel(ax4, 'Heater input Q [model units]');
                ylim(ax4, [0, max(1, 1.1*max(Q_t))]);

                yyaxis(ax4, 'right');
                hTo = plot(ax4, t, To_t, '-', 'LineWidth', 2);
                ylabel(ax4, 'Outside temperature T_o [°C]');

                legend(ax4, [hQ hTo], {'Q(t)', 'T_o(t)'}, ...
                    'Location', 'best', 'AutoUpdate', 'off');
                xlabel(ax4, 'Time [s]');
                title(ax4, sprintf('Inputs: Q = %s, T_o = %s', ...
                    Qcfg.Type, Tocfg.Type));
                xlim(ax4, [t(1) t(end)]);
                grid(ax4, 'on');

                %% 4) Animate the room and time markers
                frames = unique(round(linspace(1, numel(t), 200)));

                for i = frames
                    % Stop safely if the app or plots have been closed
                    % (also happens when Run is pressed again mid-animation)
                    if ~isvalid(app.UIFigure) || ...
                            ~all(isgraphics(hP)) || ...
                            ~all(isgraphics(hT)) || ...
                            ~isgraphics(hM2) || ~isgraphics(hM3)
                        return
                    end

                    Tvals = [To_t(i), Tw(i), Tr(i)];
                    for k = 1:3
                        hP(k).CData = Tvals(k);
                        hT(k).String = sprintf('%.1f °C', Tvals(k));
                    end

                    hM2.Value = t(i);
                    hM3.Value = t(i);
                    hTitle.String = sprintf('Time = %.2f s', t(i));
                    app.ThermostattemperatureGauge.Value = Tr(i);

                    drawnow limitrate
                    pause(0.02);   % Playback delay, independent of simulation time
                end

            catch ME
                uialert(app.UIFigure, ME.message, 'Simulation error');
            end
        end

        % Value changed function: HeaterKnob
        function HeaterKnobValueChanged(app, event)
            app.HeaterKnob.Value = round(app.HeaterKnob.Value * 2) / 2;
        end

        % Value changed function: ThermostatSwitch
        function ThermostatSwitchValueChanged(app, event)
            manualMode = strcmp(app.ThermostatSwitch.Value, 'Off');
            if manualMode
                app.HeaterKnob.Enable = 'on';
                app.DesiredTemperatureEditField.Enable = 'off';
                app.QFieldEditField.Enable = "on";
                app.ToProfileDropDown.Enable = "on";
                app.QProfileDropDown.Enable = "on";
            else
                app.HeaterKnob.Enable = 'off';
                app.DesiredTemperatureEditField.Enable = 'on';
                app.QFieldEditField.Enable = "off";
                app.ToProfileDropDown.Enable = "off";
                app.QProfileDropDown.Enable = "off";
            end
        end

        % Button pushed function: ExportCSVButton
        function ExportCSVButtonPushed(app, event)
            if isempty(app.RunHistory)
                uialert(app.UIFigure, 'Run a simulation first.', 'Nothing to export');
                return
            end

            [file, folder] = uiputfile({'*.csv', 'CSV file (*.csv)'}, ...
                'Export simulated temperatures', 'thermal_results.csv');
            figure(app.UIFigure);              % dialog sends the app to the back
            if isequal(file, 0), return, end   % user pressed Cancel

            try
                T = buildResultsTable(app);
                fullPath = fullfile(folder, file);
                writetable(T, fullPath);
                uialert(app.UIFigure, ...
                    sprintf('Saved %d rows from %d run(s) to:\n%s', ...
                    height(T), numel(app.RunHistory), fullPath), ...
                    'Export complete', 'Icon', 'success');
            catch ME
                % e.g. file is open in Excel, or folder is read-only
                uialert(app.UIFigure, ME.message, 'Export failed');
            end
        end

        % Button pushed function: SavefiguresButton
        function SavefiguresButtonPushed(app, event)
            if isempty(app.RunHistory)
                uialert(app.UIFigure, 'Run a simulation first.', 'Nothing to save');
                return
            end

            filters = {'*.png', 'PNG image (*.png)'; ...
                '*.pdf', 'PDF, vector (*.pdf)'; ...
                '*.jpg', 'JPEG image (*.jpg)'};
            [file, folder, idx] = uiputfile(filters, ...
                'Save figures (one file per plot is created)', 'thermal');
            figure(app.UIFigure);
            if isequal(file, 0), return, end

            [~, base, ext] = fileparts(file);
            if isempty(ext)
                ext = filters{idx, 1}(2:end);   % '*.png' -> '.png'
            end

            plots = {app.TemperatureAxes, 'temperatures'; ...
                app.HeatFlowAxes,    'heatflows'; ...
                app.RoomAxes,        'room'; ...
                app.InputAxes,       'inputs'};

            % Plots are on different tabs: show each tab while saving it
            originalTab = app.TabGroup.SelectedTab;
            saved = strings(0);
            try
                for k = 1:size(plots, 1)
                    ax = plots{k, 1};
                    app.TabGroup.SelectedTab = ancestor(ax, 'uitab');
                    drawnow;

                    f = fullfile(folder, sprintf('%s_%s%s', base, plots{k, 2}, ext));
                    if strcmpi(ext, '.pdf')
                        exportgraphics(ax, f, 'ContentType', 'vector');
                    else
                        exportgraphics(ax, f, 'Resolution', 300);
                    end
                    saved(end+1) = string(f); %#ok<AGROW>
                end
                app.TabGroup.SelectedTab = originalTab;
                uialert(app.UIFigure, ...
                    sprintf('Saved %d figures:\n%s', numel(saved), strjoin(saved, newline)), ...
                    'Figures saved', 'Icon', 'success');
            catch ME
                app.TabGroup.SelectedTab = originalTab;
                uialert(app.UIFigure, ME.message, 'Saving figures failed');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'C_1 = 0.5 J/K (humid / poorly insulated room)')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'temperature')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'C_2 = 1.5 J/K (dry / insulated room)')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'temperature')
            zlabel(app.UIAxes, 'Z')
            app.switchtouserinputButton = uibutton(app.Tab, 'push');
            app.switchtouserinputButton.Text = 'switch to user input';
            app.Label = uilabel(app.Tab);
            app.Label2 = uilabel(app.Tab);
            app.Label2.Text = 'Label2';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.TemperatureAxes = uiaxes(app.Tab2);
            title(app.TemperatureAxes, 'TemperatureAxes')
            xlabel(app.TemperatureAxes, 'time')
            ylabel(app.TemperatureAxes, 'temperature')
            zlabel(app.TemperatureAxes, 'Z')
            app.HeatFlowAxes = uiaxes(app.Tab2);
            title(app.HeatFlowAxes, 'Title')
            xlabel(app.HeatFlowAxes, 'X')
            ylabel(app.HeatFlowAxes, 'Y')
            zlabel(app.HeatFlowAxes, 'Z')
            app.RoomAxes = uiaxes(app.Tab2);
            title(app.RoomAxes, 'Room visualization')
            xlabel(app.RoomAxes, 'X')
            ylabel(app.RoomAxes, 'Y')
            zlabel(app.RoomAxes, 'Z')
            app.RunSimulationButton = uibutton(app.Tab2, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.RwFieldEditFieldLabel = uilabel(app.Tab2);
            app.RwFieldEditFieldLabel.Text = 'RwField';
            app.RwFieldEditField = uieditfield(app.Tab2, 'numeric');
            app.RwFieldEditField.Value = 1.8;
            app.C1FieldEditFieldLabel = uilabel(app.Tab2);
            app.C1FieldEditFieldLabel.Text = 'C1Field';
            app.C1FieldEditField = uieditfield(app.Tab2, 'numeric');
            app.C1FieldEditField.Value = 0.5;
            app.C2FieldEditFieldLabel = uilabel(app.Tab2);
            app.C2FieldEditFieldLabel.Text = 'C2Field';
            app.C2FieldEditField = uieditfield(app.Tab2, 'numeric');
            app.C2FieldEditField.Value = 1.5;
            app.RrFieldEditFieldLabel = uilabel(app.Tab2);
            app.RrFieldEditFieldLabel.Text = 'RrField';
            app.RrFieldEditField = uieditfield(app.Tab2, 'numeric');
            app.RrFieldEditField.Value = 0.5;
            app.stopsimulationButton = uibutton(app.Tab2, 'push');
            app.stopsimulationButton.Text = 'stop simulation';
            app.ThermostatSwitchLabel = uilabel(app.Tab2);
            app.ThermostatSwitchLabel.Text = 'Thermostat';
            app.ThermostatSwitch = uiswitch(app.Tab2, 'toggle');
            app.ThermostatSwitch.ValueChangedFcn = createCallbackFcn(app, @ThermostatSwitchValueChanged, true);
            app.DesiredroomtemperatureLabel = uilabel(app.Tab2);
            app.DesiredroomtemperatureLabel.Text = {'Desired room'; 'temperature'};
            app.DesiredTemperatureEditField = uieditfield(app.Tab2, 'numeric');
            app.DesiredTemperatureEditField.Value = 14.5;
            app.GaugeLabel = uilabel(app.Tab2);
            app.GaugeLabel.Text = 'Thermostat temperature';
            app.ThermostattemperatureGauge = uigauge(app.Tab2, 'semicircular');
            app.ThermostattemperatureGauge.Limits = [0 50];
            app.HeaterKnobLabel = uilabel(app.Tab2);
            app.HeaterKnobLabel.Text = 'Heater Knob';
            app.HeaterKnob = uiknob(app.Tab2, 'continuous');
            app.HeaterKnob.Limits = [0 10];
            app.HeaterKnob.ValueChangedFcn = createCallbackFcn(app, @HeaterKnobValueChanged, true);
            app.HeaterKnob.Value = 5;
            app.HoldpreviousrunsCheckBox = uicheckbox(app.Tab2);
            app.HoldpreviousrunsCheckBox.Text = 'Hold previous runs';
            app.ExportCSVButton = uibutton(app.Tab2, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButtonPushed, true);
            app.ExportCSVButton.Text = 'Export CSV';
            app.SavefiguresButton = uibutton(app.Tab2, 'push');
            app.SavefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SavefiguresButtonPushed, true);
            app.SavefiguresButton.Text = 'Save figures';
            app.Gauge_2 = uigauge(app.Tab2, 'linear');
            app.Gauge_2.Limits = [0 30];
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab5';
            app.InputAxes = uiaxes(app.Tab4);
            title(app.InputAxes, 'Title')
            xlabel(app.InputAxes, 'X')
            ylabel(app.InputAxes, 'Y')
            zlabel(app.InputAxes, 'Z')
            app.ToFieldEditFieldLabel = uilabel(app.Tab4);
            app.ToFieldEditFieldLabel.Text = 'ToField';
            app.ToFieldEditField = uieditfield(app.Tab4, 'numeric');
            app.ToFieldEditField.Value = 3;
            app.Tr0FieldEditFieldLabel = uilabel(app.Tab4);
            app.Tr0FieldEditFieldLabel.Text = 'Tr0Field';
            app.Tr0FieldEditField = uieditfield(app.Tab4, 'numeric');
            app.Tr0FieldEditField.Value = 8;
            app.Tw0FieldEditFieldLabel = uilabel(app.Tab4);
            app.Tw0FieldEditFieldLabel.Text = 'Tw0Field';
            app.Tw0FieldEditField = uieditfield(app.Tab4, 'numeric');
            app.Tw0FieldEditField.Value = 4;
            app.TendFieldEditFieldLabel = uilabel(app.Tab4);
            app.TendFieldEditFieldLabel.Text = 'TendField';
            app.TendFieldEditField = uieditfield(app.Tab4, 'numeric');
            app.TendFieldEditField.Value = 30;
            app.QFieldEditFieldLabel = uilabel(app.Tab4);
            app.QFieldEditFieldLabel.Text = 'QField';
            app.QFieldEditField = uieditfield(app.Tab4, 'numeric');
            app.QFieldEditField.Value = 3;
            app.HeaterQDropDownLabel = uilabel(app.Tab4);
            app.HeaterQDropDownLabel.Text = 'Heater, Q';
            app.QProfileDropDown = uidropdown(app.Tab4);
            app.QProfileDropDown.Items = {'Constant', 'Step', 'Sinusoidal', 'Random'};
            app.QProfileDropDown.Value = 'Constant';
            app.OutsideToDropDownLabel = uilabel(app.Tab4);
            app.OutsideToDropDownLabel.Text = 'Outside, To';
            app.ToProfileDropDown = uidropdown(app.Tab4);
            app.ToProfileDropDown.Items = {'Constant', 'Daily sinusoid', 'Random around mean'};
            app.ToProfileDropDown.Value = 'Constant';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thermo_sim_sketchv9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end