classdef thermo_sim_sketchv13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StartPage                       matlab.ui.container.Tab
        Label2_4                        matlab.ui.control.Label
        Label2_3                        matlab.ui.control.Label
        Label2_2                        matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label2_5                        matlab.ui.control.Label
        Group2FinalLabel                matlab.ui.control.Label
        Inputs                          matlab.ui.container.Tab
        ResettoDefaultValuesButton      matlab.ui.control.Button
        RwFieldEditField                matlab.ui.control.NumericEditField
        RwFieldEditFieldLabel           matlab.ui.control.Label
        TimevaryinginputsandLabel       matlab.ui.control.Label
        TimevaryinginputsandLabel_2     matlab.ui.control.Label
        InitialParametersLabel          matlab.ui.control.Label
        C2EditField                     matlab.ui.control.NumericEditField
        C2FieldEditFieldLabel           matlab.ui.control.Label
        QRandomseedEditField            matlab.ui.control.NumericEditField
        RandomseedEditField_2Label      matlab.ui.control.Label
        QHoldtimesEditField             matlab.ui.control.NumericEditField
        HoldtimesEditField_3Label       matlab.ui.control.Label
        QPeriodsEditField               matlab.ui.control.NumericEditField
        PeriodsEditFieldLabel           matlab.ui.control.Label
        QAmplitudeEditField             matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        QSteptimesEditField             matlab.ui.control.NumericEditField
        SteptimesEditFieldLabel         matlab.ui.control.Label
        QValueafterstepEditField        matlab.ui.control.NumericEditField
        Valueafterstep010Label          matlab.ui.control.Label
        C1EditField                     matlab.ui.control.NumericEditField
        C1FieldEditFieldLabel           matlab.ui.control.Label
        RefreshInputGraphsButton        matlab.ui.control.Button
        ToHoldtimesEditField            matlab.ui.control.NumericEditField
        HoldtimesEditField_2Label       matlab.ui.control.Label
        ToRandomseedEditField           matlab.ui.control.NumericEditField
        RandomseedEditFieldLabel        matlab.ui.control.Label
        ToPeriodEditField               matlab.ui.control.NumericEditField
        Period1daysEditFieldLabel       matlab.ui.control.Label
        ToAmplitudedegCEditField        matlab.ui.control.NumericEditField
        AmplitudedegCEditFieldLabel     matlab.ui.control.Label
        RrFieldEditField                matlab.ui.control.NumericEditField
        ThermalResistanceRoomWallLabel  matlab.ui.control.Label
        QProfileDropDown                matlab.ui.control.DropDown
        HeaterProfileLabel              matlab.ui.control.Label
        MeanHeaterPower010EditField     matlab.ui.control.NumericEditField
        MeanHeaterPowerEditFieldLabel   matlab.ui.control.Label
        ToProfileDropDown               matlab.ui.control.DropDown
        OutsideTemperatureProfileLabel  matlab.ui.control.Label
        SimulationTimesEditField        matlab.ui.control.NumericEditField
        SimulationTimesEditFieldLabel   matlab.ui.control.Label
        InitialWallTempdegCEditField    matlab.ui.control.NumericEditField
        Tw0FieldEditFieldLabel          matlab.ui.control.Label
        InitialRoomTempdegCEditField    matlab.ui.control.NumericEditField
        Tr0FieldEditFieldLabel          matlab.ui.control.Label
        ToFieldEditField                matlab.ui.control.NumericEditField
        MeanOusideTemperaturedegCLabel  matlab.ui.control.Label
        InputAxes                       matlab.ui.control.UIAxes
        AnimationsTab                   matlab.ui.container.Tab
        DesiredTemperatureEditField     matlab.ui.control.NumericEditField
        DesiredroomtemperatureLabel     matlab.ui.control.Label
        Gauge_2                         matlab.ui.control.LinearGauge
        SaveFiguresButton               matlab.ui.control.Button
        ExportCSVButton                 matlab.ui.control.Button
        HoldpreviousrunsCheckBox        matlab.ui.control.CheckBox
        HeaterKnob                      matlab.ui.control.Knob
        HeaterPowerKnobLabel            matlab.ui.control.Label
        ThermostattemperatureGauge      matlab.ui.control.SemicircularGauge
        GaugeLabel                      matlab.ui.control.Label
        ThermostatSwitch                matlab.ui.control.ToggleSwitch
        ThermostatSwitchLabel           matlab.ui.control.Label
        stopsimulationButton            matlab.ui.control.Button
        RunSimulationButton             matlab.ui.control.Button
        HeatFlowAxes                    matlab.ui.control.UIAxes
        RoomAxes                        matlab.ui.control.UIAxes
        TemperatureAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        RunHistory = struct('C1', {},'C2',{}, 'Rr', {},'Rw',{},'Qprofile', {},'Toprofile',{}, 't', {},'Tr',{},'Tw', {},'To',{}, 'Q', {});
    end
    
    properties (Access = private)
                
                C1 ; C2 ;
                Rr ; Rw ;
                Q  ;
                To ;
                Tr0;
                Tw0;
                t_end ;
                Q_amp ;
                Q_step ;
                Q_t_prd;
                Q_t_step ;
                Q_t_hold ;
                Q_rnd ;
                To_amp ;
                To_t_prd ;
                To_t_hold ;
                To_rnd ;
                StopRequested = false;
                RunningSimulation = false;

    end


methods (Access = private)

    function UpdateInputs(app)

        manualMode = strcmp(app.ThermostatSwitch.Value, 'Off');

        % Reset heater controls
        app.HeaterKnob.Enable = 'off';
        app.MeanHeaterPower010EditField.Enable = 'off';
        app.QAmplitudeEditField.Enable = 'off';
        app.QValueafterstepEditField.Enable = 'off';
        app.QPeriodsEditField.Enable = 'off';
        app.QSteptimesEditField.Enable = 'off';
        app.QHoldtimesEditField.Enable = 'off';
        app.QRandomseedEditField.Enable = 'off';

        app.QProfileDropDown.Enable = 'off';
        app.DesiredTemperatureEditField.Enable = 'on';

        if manualMode
            app.QProfileDropDown.Enable = 'on';
            app.DesiredTemperatureEditField.Enable = 'off';

            switch lower(string(app.QProfileDropDown.Value))
                case "constant"
                    app.HeaterKnob.Enable = 'on';
                    app.MeanHeaterPower010EditField.Enable = 'on';

                case "step"
                    app.HeaterKnob.Enable = 'on';
                    app.MeanHeaterPower010EditField.Enable = 'on';
                    app.QValueafterstepEditField.Enable = 'on';
                    app.QSteptimesEditField.Enable = 'on';

                case {"sinusoidal", "daily sinusoid"}
                    app.MeanHeaterPower010EditField.Enable = 'on';
                    app.QAmplitudeEditField.Enable = 'on';
                    app.QPeriodsEditField.Enable = 'on';

                case {"random", "random around mean"}
                    app.MeanHeaterPower010EditField.Enable = 'on';
                    app.QAmplitudeEditField.Enable = 'on';
                    app.QHoldtimesEditField.Enable = 'on';
                    app.QRandomseedEditField.Enable = 'on';

                otherwise
                    error('Unknown heater profile type "%s".', ...
                        string(app.QProfileDropDown.Value));
            end
        end

        % Reset outside-temperature controls
        app.ToProfileDropDown.Enable = 'on';
        app.ToFieldEditField.Enable = 'on';
        app.ToAmplitudedegCEditField.Enable = 'off';
        app.ToPeriodEditField.Enable = 'off';
        app.ToHoldtimesEditField.Enable = 'off';
        app.ToRandomseedEditField.Enable = 'off';

        switch lower(string(app.ToProfileDropDown.Value))
            case "constant"
                % Only ToFieldEditField is needed

            case {"sinusoidal", "daily sinusoid"}
                app.ToAmplitudedegCEditField.Enable = 'on';
                app.ToPeriodEditField.Enable = 'on';

            case {"random", "random around mean"}
                app.ToAmplitudedegCEditField.Enable = 'on';
                app.ToHoldtimesEditField.Enable = 'on';
                app.ToRandomseedEditField.Enable = 'on';

            otherwise
                error('Unsupported outside-temperature profile type "%s".', ...
                    string(app.ToProfileDropDown.Value));
        end
        Qcfg.Type   = app.QProfileDropDown.Value;
        Qcfg.Level  = app.HeaterKnob.Value;
        Qcfg.Mean   = app.MeanHeaterPower010EditField.Value;
        Qcfg.Level2 = app.QValueafterstepEditField.Value;
        Qcfg.TStep  = app.QSteptimesEditField.Value;
        Qcfg.Amp    = app.QAmplitudeEditField.Value;
        Qcfg.Period = app.QPeriodsEditField.Value;
        Qcfg.Hold   = app.QHoldtimesEditField.Value;
        Qcfg.Seed   = app.QRandomseedEditField.Value;

        Tocfg.Type   = app.ToProfileDropDown.Value;
        Tocfg.Level  = app.ToFieldEditField.Value;
        Tocfg.Mean   = app.ToFieldEditField.Value;
        Tocfg.Amp    = app.ToAmplitudedegCEditField.Value;
        Tocfg.Period = app.ToPeriodEditField.Value;
        Tocfg.Hold   = app.ToHoldtimesEditField.Value;
        Tocfg.Seed   = app.ToRandomseedEditField.Value;

        app.C1 = app.C1EditField.Value;
        app.C2 = app.C2EditField.Value;
        app.Tr0 = app.InitialRoomTempdegCEditField.Value;
        app.Tw0 = app.InitialWallTempdegCEditField.Value;
        app.Rr = app.RrFieldEditField.Value;
        app.Rw = app.RwFieldEditField.Value;
        app.t_end = app.SimulationTimesEditField.Value;

        Tset = app.DesiredTemperatureEditField.Value;

        out = app.ThermalModelTV(Qcfg, Tocfg, Tset, manualMode);

        t  = out.t(:);

        To_t = out.To(:);
        Q_t  = out.Q(:);

        ax4 = app.InputAxes;
        % With two y-axes, cla only clears the active side: clear both
        yyaxis(ax4, 'left');  cla(ax4);
        yyaxis(ax4, 'right'); cla(ax4);

        yyaxis(ax4, 'left');
        hQ = plot(ax4, t, Q_t, '-', 'LineWidth', 2);
        ylabel(ax4, 'Heater input Q [model units]');
        ylim(ax4, [0, max(1, 1.1.*max(Q_t))]);

        yyaxis(ax4, 'right');
        hTo = plot(ax4, t, To_t, '-', 'LineWidth', 2);
        ylabel(ax4, 'Outside temperature T_o [°C]');

        legend(ax4, [hQ hTo], {'Q(t)', 'T_o(t)'}, ...
            'Location', 'southeast', 'AutoUpdate', 'off');
        xlabel(ax4, 'Time [s]');
        title(ax4, sprintf('Inputs: Q = %s, T_o = %s', ...
            Qcfg.Type, Tocfg.Type));
        xlim(ax4, [t(1) t(end)]);
        grid(ax4, 'on');
    
    end

    function out = ThermalModelTV(app, Qcfg, Tocfg, Tset, manualMode)

        % 1) Check model parameters
        if any(~isfinite([app.C1  app.C2  app.Rr  app.Rw  app.Tr0  app.Tw0  app.t_end Tset Qcfg.Level2 Qcfg.TStep Qcfg.Amp Qcfg.Period Qcfg.Hold Qcfg.Seed Tocfg.Level Tocfg.Amp Tocfg.Period Tocfg.Hold Tocfg.Seed])) || ...
                any([app.C1  app.C2  app.Rr  app.Rw  app.t_end] <= 0)
            uialert(app.UIFigure,'Use finite inputs and positive capacities, resistances and duration.', 'Problematic Input');
            return
        end

        if any([app.Tr0 app.Tw0 Tocfg.Level] < -20)
            uialert(app.UIFigure,'Please adjust temperature and restart.','Unrealistic Temperature')
            return
        end

        if Tset < Tocfg.Level
            uialert(app.UIFigure,'Desired Temperature is below outside temperature. Please adjust and restart.','Infeasible Desired Room Temperature')
            return
        end

        % 2) State-space model
        A = [-1/(app.Rr*app.C1),  1/(app.Rr*app.C1);
              1/(app.Rr*app.C2), -(1/app.Rr + 1/app.Rw)/app.C2];

        B = [1/app.C1, 0;
             0,    1/(app.Rw*app.C2)];

        plant = ss(A, B, eye(2), zeros(2));

        plant.InputName  = {'Q', 'To'};
        plant.OutputName = {'Tr', 'Tw'};
        plant.StateName  = {'RoomTemperature', 'WallTemperature'};

        t = linspace(0, app.t_end, 3001)';

        %% 3) Generate input profiles
        % Automatic mode only needs the outside-temperature profile.
        configs = {Qcfg, Tocfg};
        signals = zeros(numel(t), 2);
        nominals = zeros(1, 2);

        for j = 1:2

            if j == 1 && ~manualMode
                continue
            end

            p = configs{j};

            switch lower(string(p.Type))
                case "constant"
                    u = p.Level .* ones(size(t));
                    nominal = p.Level;

                case "step"
                    u = p.Level .* ones(size(t));
                    u(t >= p.TStep) = p.Level2;
                    nominal = p.Level2;

                case {"sinusoidal", "daily sinusoid"}
                    if ~isfinite(p.Period) || p.Period <= 0
                        error('Sinusoidal period must be positive and finite.');
                    end

                    u = p.Mean + p.Amp .* sin(2*pi.*t/p.Period);
                    nominal = p.Mean;

                case {"random", "random around mean"}
                    if ~isfinite(p.Hold) || p.Hold <= 0
                        error('Random hold time must be positive and finite.');
                    end

                    rng(p.Seed);

                    interval = floor(t/p.Hold) + 1;
                    random_values = p.Mean + p.Amp .* randn(max(interval), 1);

                    u = random_values(interval);
                    nominal = p.Mean;

                otherwise
                    error('Unknown profile type "%s".', string(p.Type));
            end

            if any(~isfinite(u))
                error('Input profiles must contain finite values.');
            end

            signals(:,j) = u;
            nominals(j) = nominal;
        end

        To_local = signals(:,2);
        Tonom = nominals(2);

        %% 4) Choose manual or automatic simulation
        if manualMode

            % Manual heater power from Qcfg
            Q_local = min(max(signals(:,1), 0), 10);
            Qnom = min(max(nominals(1), 0), 10);

            y = lsim(plant, [Q_local  To_local], t, [app.Tr0;  app.Tw0]);

            out.Tr = y(:,1);
            out.Tw = y(:,2);
            out.Q  =  Q_local;

            % Reference equilibrium for nominal inputs
            out.Tr_ss = Tonom + Qnom*( app.Rr +  app.Rw);
            out.Tw_ss = Tonom + Qnom* app.Rw;
            out.Q_ss  = Qnom;

            out.lambda = sort(eig(A), 'descend');
            out.tau = -1./ out.lambda;

            % No PID controller in manual mode
            out.Kp = NaN;
            out.Ki = NaN;
            out.Kd = NaN;
            out.Tf = NaN;

        else

            % Tune heater power -> room temperature
            controller = pidtune(plant(1,1), 'PIDF');

            controller.InputName  = 'error';
            controller.OutputName = 'Q';

            errorBlock = sumblk('error = Tset - Tr');

            % Preserve states for initial-temperature assignment
            options = connectOptions('Simplify', false);

            closedLoop = connect(plant, controller, errorBlock, ...
                {'Tset', 'To'}, {'Tr', 'Tw', 'Q'}, options);

            % Room/wall initial temperatures; controller states start at zero
            x0 = zeros(size(closedLoop.A,1), 1);

            roomInterval = strcmp(closedLoop.StateName, 'RoomTemperature');
            wallInterval = strcmp(closedLoop.StateName, 'WallTemperature');

            if nnz(roomInterval) ~= 1 || nnz(wallInterval) ~= 1
                error('Could not identify the room and wall states.');
            end

            x0(roomInterval) =  app.Tr0;
            x0(wallInterval) =  app.Tw0;

            TsetProfile = Tset .* ones(size(t));

            y = lsim(closedLoop, [TsetProfile To_local], t, x0);

            out.Tr = y(:,1);
            out.Tw = y(:,2);
            out.Q  = y(:,3);

            out.Q(1) = 0;
            % trying to correct Q < 0 and Q > 10
            if out.Q(end) > 10
                uialert(app.UIFigure, 'Heater Power is not high enough to reach Desired Room Temperature.','Limited Heater Power')
            end
            if out.Q(end) < 0
                uialert(app.UIFigure, 'Heater Power cannot go below zero to reach Desired Room Temperature.','No cooling possible with Heater')
            end

            Q_corr = min(max(out.Q, 0), 10);

            y = lsim(plant, [Q_corr  To_local], t, [app.Tr0;  app.Tw0]);

            out.Tr = y(:,1);
            out.Tw = y(:,2);
            out.Q  =  Q_corr;

            out.lambda = sort(eig(A), 'descend');
            out.tau = -1./ out.lambda;
            % remaining PID code


            out.Kp = controller.Kp;
            out.Ki = controller.Ki;
            out.Kd = controller.Kd;
            out.Tf = controller.Tf;

            % Reference equilibrium assuming stable target tracking
            out.Tr_ss = Tset;
            out.Q_ss  = (Tset - Tonom)/( app.Rr +  app.Rw);
            out.Tw_ss = Tonom + out.Q_ss* app.Rw;

        end

        %% 5) Common outputs
        out.t = t;
        out.To = To_local;
        out.Tset = Tset;
        out.manualMode = manualMode;

    end
    
        function T = buildResultsTable(app)
            % One table: one block of rows per run, parameters on every row
            parts = cell(numel(app.RunHistory), 1);
            for k = 1:numel(app.RunHistory)
                r = app.RunHistory(k);
                n = numel(r.t);
                parts{k} = table( ...
                    repmat(k, n, 1), ...
                    repmat(r.C1, n, 1), repmat(r.C2, n, 1), ...
                    repmat(r.Rr, n, 1), repmat(r.Rw, n, 1), ...
                    repmat(string(r.Qprofile), n, 1), ...
                    repmat(string(r.Toprofile), n, 1), ...
                    r.t, r.Tr, r.Tw, r.To, r.Q, ...
                    'VariableNames', {'Run', 'C1', 'C2', 'Rr', 'Rw', ...
                    'Q_profile', 'To_profile', 'Time_s', ...
                    'T_room_degC', 'T_wall_degC', 'T_outside_degC', 'Q_heater'});
            end
            T = vertcat(parts{:});
        end
    
        function setExportEnabled(app, onOff)
            app.ExportCSVButton.Enable   = onOff;
            app.SaveFiguresButton.Enable = onOff;
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HeaterKnob.Enable = 'on';
            app.ToProfileDropDown.Enable = 'on';
            app.QProfileDropDown.Enable = 'on';
            app.MeanHeaterPower010EditField.Enable = 'on';
            app.ThermostatSwitch.Value = 'Off';
            app.DesiredTemperatureEditField.Enable = 'off';
            app.C1 = 0.5;
            app.C1EditField.Value = 0.5;
            app.C2 = 1.5;
            app.C2EditField.Value = 1.5;
            app.Rr = 0.5;
            app.RrFieldEditField.Value = 0.5;
            app.Rw = 1.8;
            app.RwFieldEditField.Value = 1.8;
            app.Q = 5;
            app.MeanHeaterPower010EditField.Value = 5;
            app.To = 3 ;
            app.ToFieldEditField.Value = 3;
            app.Tr0  = 8; 
            app.InitialRoomTempdegCEditField.Value = 8;
            app.Tw0  = 4;
            app.InitialWallTempdegCEditField.Value = 4;
            app.t_end = 30;
            app.SimulationTimesEditField.Value =30;
            app.Q_amp = 2;
            app.QAmplitudeEditField.Value = 2;
            app.Q_step = 8;
            app.QValueafterstepEditField.Value = 8;
            app.Q_t_prd = 10;
            app.QPeriodsEditField.Value = 10;
            app.Q_t_step = 10;
            app.QSteptimesEditField.Value = 10;
            app.Q_t_hold = 1;
            app.QHoldtimesEditField.Value = 1;
            app.Q_rnd = 1;
            app.QRandomseedEditField.Value =1 ;
            app.To_amp = 2;
            app.ToAmplitudedegCEditField.Value =2;
            app.To_t_prd = 24;
            app.ToPeriodEditField.Value=24;
            app.To_t_hold = 1;
            app.ToHoldtimesEditField.Value=1;
            app.To_rnd = 2;
            app.ToRandomseedEditField.Value=2 ;
            app.UpdateInputs();
            setExportEnabled(app, 'off');   % nothing to export before the first run
            app.StopRequested = false;
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            if app.RunningSimulation
                msgbox("Please stop the Simuation before you start a new one! In case of errors, please restart the app.")
                return
            end
            app.RunningSimulation = true;
            try

                Qcfg.Type   = app.QProfileDropDown.Value;
                Qcfg.Level  = app.HeaterKnob.Value;
                Qcfg.Mean   = app.MeanHeaterPower010EditField.Value;
                Qcfg.Level2 = app.QValueafterstepEditField.Value;
                Qcfg.TStep  = app.QSteptimesEditField.Value;
                Qcfg.Amp    = app.QAmplitudeEditField.Value;
                Qcfg.Period = app.QPeriodsEditField.Value;
                Qcfg.Hold   = app.QHoldtimesEditField.Value;
                Qcfg.Seed   = app.QRandomseedEditField.Value;

                Tocfg.Type   = app.ToProfileDropDown.Value;
                Tocfg.Level  = app.ToFieldEditField.Value;
                Tocfg.Mean   = app.ToFieldEditField.Value;
                Tocfg.Amp    = app.ToAmplitudedegCEditField.Value;
                Tocfg.Period = app.ToPeriodEditField.Value;
                Tocfg.Hold   = app.ToHoldtimesEditField.Value;
                Tocfg.Seed   = app.ToRandomseedEditField.Value;

                app.UpdateInputs();

                manualMode = strcmp(app.ThermostatSwitch.Value, 'Off');
                Tset = app.DesiredTemperatureEditField.Value;

                out = app.ThermalModelTV(Qcfg, Tocfg, Tset, manualMode);
                %% Prepare simulation results (force column vectors)
                t  = out.t(:);
                Tr = out.Tr(:);
                Tw = out.Tw(:);

                To_t = out.To(:);
                Q_t  = out.Q(:);

                Q_rw   = (Tr - Tw) /  app.Rr;
                Q_wo   = (Tw - To_t) /  app.Rw;
                %% Store this run (used by hold + exports)
                thisRun = struct('C1', app.C1, 'C2', app.C2, 'Rr', app.Rr, 'Rw',  app.Rw, ...
                    'Qprofile', Qcfg.Type, 'Toprofile', Tocfg.Type, ...
                    't', t, 'Tr', Tr, 'Tw', Tw, 'To', To_t, 'Q', Q_t);

                holdOn = app.HoldpreviousrunsCheckBox.Value;
                if holdOn
                    app.RunHistory(end+1) = thisRun;
                else
                    app.RunHistory = thisRun;
                end
                runNo = numel(app.RunHistory);
                setExportEnabled(app, 'on');

                % Colour range for the room diagram (current run only)
                allTemps = [Tr; Tw; To_t; out.Tr_ss; out.Tw_ss];
                Tmin = min(allTemps) - 1;
                Tmax = max(allTemps) + 1;

                %% Use the axes already in the app
                ax1 = app.RoomAxes;
                ax2 = app.TemperatureAxes;
                ax3 = app.HeatFlowAxes;

                cla(ax1);
                cla(ax3);

                % Only clear the temperature plot when not holding.
                % When holding, delete the previous run's annotations
                % (steady-state lines, tau lines, time marker, outside
                % temperature) but keep its temperature curves.
                if holdOn
                    delete(findobj(ax2, 'Tag', 'annot'));
                else
                    cla(ax2);
                end

                %% 1) Room diagram
                hold(ax1, 'on');

                zones = {[0 1], [1 2], [2 5]};
                zoneNames = {'Outside', 'Wall', 'Room'};

                hP = gobjects(1, 3);
                hT = gobjects(1, 3);

                for k = 1:3
                    left  = zones{k}(1);
                    right = zones{k}(2);

                    hP(k) = patch(ax1, ...
                        [left right right left], [0 0 3 3], To_t(1), ...
                        'FaceColor', 'flat', ...
                        'EdgeColor', 'k', 'LineWidth', 1.5);

                    text(ax1, mean(zones{k}), 2.75, zoneNames{k}, ...
                        'HorizontalAlignment', 'center', ...
                        'FontWeight', 'bold');

                    hT(k) = text(ax1, mean(zones{k}), 1.8, '', ...
                        'HorizontalAlignment', 'center', ...
                        'FontSize', 12, 'FontWeight', 'bold', ...
                        'BackgroundColor', [1 1 1]);
                end

                % Heater
                patch(ax1, [4.6 4.9 4.9 4.6], [0.3 0.3 1.3 1.3], ...
                    [0.4 0.4 0.4]);
                text(ax1, 4.75, 1.45, 'Heater', ...
                    'HorizontalAlignment', 'center', 'FontSize', 9);

                colormap(ax1, turbo);
                clim(ax1, [Tmin Tmax]);

                cb = colorbar(ax1);
                cb.Label.String = 'Temperature [°C]';

                axis(ax1, 'equal');
                axis(ax1, 'off');
                xlim(ax1, [-0.1 5.1]);
                ylim(ax1, [-0.1 3.1]);

                hTitle = title(ax1, '');
                hold(ax1, 'off');

                %% 2) Temperature curves 
                hold(ax2, 'on');

                % One colour per run; solid = room, dashed = wall
                cols = lines(7);
                c = cols(mod(runNo - 1, 7) + 1, :);

                runLabel = sprintf('T_r and T_w (Run %d)', runNo);

                plot(ax2, t, Tr, '-', 'Color', c, 'LineWidth', 2, ...
                    'Tag', 'runTr', 'DisplayName', runLabel);
                plot(ax2, t, Tw, '--', 'Color', c, 'LineWidth', 1.5, ...
                    'Tag', 'runTw');

                % Annotations for the CURRENT run only (all tagged 'annot')
                hOut = plot(ax2, t, To_t, 'k-.', 'LineWidth', 1, ...
                    'Tag', 'annot', 'DisplayName', 'T_o (current run)');

                yline(ax2, out.Tr_ss, ':', ...
                    sprintf('T_{r,ss} = %.2f °C', out.Tr_ss), ...
                    'Color', c, 'Tag', 'annot');
                yline(ax2, out.Tw_ss, ':', ...
                    sprintf('T_{w,ss} = %.2f °C', out.Tw_ss), ...
                    'Color', c, 'Tag', 'annot', ...
                    'LabelVerticalAlignment', 'bottom');

                xline(ax2, out.tau(1), '--', ...
                    sprintf('\\tau_1 = %.2f', out.tau(1)), ...
                    'Color', [0.4 0.4 0.4], 'Tag', 'annot');
                xline(ax2, out.tau(2), '--', ...
                    sprintf('\\tau_2 = %.2f', out.tau(2)), ...
                    'Color', [0.4 0.4 0.4], 'Tag', 'annot');

                % Legend: one entry per run (oldest first) + outside temperature.
                % findobj returns newest first, hence flipud.
                hRuns = flipud(findobj(ax2, 'Tag', 'runTr'));
                lg = legend(ax2, [hRuns; hOut]);
                lg.Location   = 'southeast';  % keep the legend off the curves
                lg.AutoUpdate = 'off';          % later objects don't join the legend
                lg.Title.String = 'solid: T_{r}   dashed: T_{w}';

                xlabel(ax2, 'Time [s]');
                ylabel(ax2, 'Temperature [°C]');
                if holdOn && runNo > 1
                    title(ax2, sprintf('Temperature curves (%d runs held)', runNo));
                else
                    title(ax2, 'Temperature curves');
                end
                tMax = max(arrayfun(@(r) r.t(end), app.RunHistory));
                xlim(ax2, [0 tMax]);
                grid(ax2, 'on');
                % exportgraphics(ax2,"validationcurve_.pdf", "ContentType","vector");
                hM2 = xline(ax2, t(1), 'k', 'LineWidth', 1.5, 'Tag', 'annot');
                hold(ax2, 'off');

                %% 3) Heat-flow curves (always current run only)
                hold(ax3, 'on');

                hHeat = plot(ax3, t, Q_t, ...
                    'Color', [0.85 0.33 0.1], 'LineWidth', 2);
                hRW = plot(ax3, t, Q_rw, ...
                    'Color', [0.5 0 0.5], 'LineWidth', 2);
                hWO = plot(ax3, t, Q_wo, ...
                    'Color', [0 0.45 0.74], 'LineWidth', 2);

                hM3 = xline(ax3, t(1), 'k', 'LineWidth', 1.5);

                legend(ax3, [hHeat hRW hWO], ...
                    {'Heater to room', 'Room to wall', 'Wall to outside'}, ...
                    'Location', 'best', 'AutoUpdate', 'off');

                xlabel(ax3, 'Time [s]');
                ylabel(ax3, 'Heat flow [model units]');
                title(ax3, 'Heat flows');
                xlim(ax3, [t(1) t(end)]);
                grid(ax3, 'on');
                hold(ax3, 'off');

                %% 4) Animate the room and time markers
                frames = unique(round(linspace(1, numel(t), 200)));

                for i = frames
                    % Stop safely if the app or plots have been closed
                    % (also happens when Run is pressed again mid-animation)
                    if ~isvalid(app.UIFigure) || ...
                            ~all(isgraphics(hP)) || ...
                            ~all(isgraphics(hT)) || ...
                            ~isgraphics(hM2) || ~isgraphics(hM3)
                        return
                    end
                    if app.StopRequested
                        app.UpdateInputs();
                        app.RunningSimulation = false;
                        app.StopRequested = false;
                        break
                    end

                    Tvals = [To_t(i), Tw(i), Tr(i)];
                    for k = 1:3
                        hP(k).CData = Tvals(k);
                        hT(k).String = sprintf('%.1f °C', Tvals(k));
                    end

                    hM2.Value = t(i);
                    hM3.Value = t(i);
                    hTitle.String = sprintf('Time = %.2f s', t(i));
                    app.ThermostattemperatureGauge.Value = Tr(i);
                    app.Gauge_2.Value = t(i);
                    if Q_t(i) > 10
                        app.HeaterKnob.Value = 10;
                    elseif Q_t(i) < 0
                        app.HeaterKnob.Value = 0;
                    else
                        app.HeaterKnob.Value = Q_t(i);
                    end

                    drawnow limitrate
                    pause(0.05);   % Playback delay, independent of simulation time
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Simulation error');
            end
            app.RunningSimulation = false;
        end

        % Value changed function: HeaterKnob
        function HeaterKnobValueChanged(app, event)
            app.HeaterKnob.Value = round(app.HeaterKnob.Value * 2) / 2;
            app.MeanHeaterPower010EditField.Value = app.HeaterKnob.Value;
        end

        % Value changed function: ThermostatSwitch
        function ThermostatSwitchValueChanged(app, event)
            manualMode = strcmp(app.ThermostatSwitch.Value, 'Off');
            if manualMode
                app.HeaterKnob.Enable = 'on';
                app.DesiredTemperatureEditField.Enable = 'off';
                app.MeanHeaterPower010EditField.Enable = "on";
                app.ToProfileDropDown.Enable = "on";
                app.QProfileDropDown.Enable = "on";
            else
                app.HeaterKnob.Enable = 'off';
                app.DesiredTemperatureEditField.Enable = 'on';
                app.MeanHeaterPower010EditField.Enable = "off";
                app.ToProfileDropDown.Enable = "off";
                app.QProfileDropDown.Enable = "off";
            end
        end

        % Button pushed function: ExportCSVButton
        function ExportCSVButtonPushed(app, event)
            if isempty(app.RunHistory)
                uialert(app.UIFigure, 'Run a simulation first.', 'Nothing to export');
                return
            end

            [file, folder] = uiputfile({'*.csv', 'CSV file (*.csv)'}, ...
                'Export simulated temperatures', 'thermal_results.csv');
            figure(app.UIFigure);              % dialog sends the app to the back
            if isequal(file, 0), return, end   % user pressed Cancel

            try
                T = buildResultsTable(app);
                fullPath = fullfile(folder, file);
                writetable(T, fullPath);
                uialert(app.UIFigure, ...
                    sprintf('Saved %d rows from %d run(s) to:\n%s', ...
                    height(T), numel(app.RunHistory), fullPath), ...
                    'Export complete', 'Icon', 'success');
            catch ME
                % e.g. file is open in Excel, or folder is read-only
                uialert(app.UIFigure, ME.message, 'Export failed');
            end
        end

        % Button pushed function: SaveFiguresButton
        function SaveFiguresButtonPushed(app, event)
            if isempty(app.RunHistory)
                uialert(app.UIFigure, 'Run a simulation first.', 'Nothing to save');
                return
            end

            filters = {'*.png', 'PNG image (*.png)'; ...
                '*.pdf', 'PDF, vector (*.pdf)'; ...
                '*.jpg', 'JPEG image (*.jpg)'};
            [file, folder, idx] = uiputfile(filters, ...
                'Save figures (one file per plot is created)', 'thermal');
            figure(app.UIFigure);
            if isequal(file, 0), return, end

            [~, base, ext] = fileparts(file);
            if isempty(ext)
                ext = filters{idx, 1}(2:end);   % '*.png' -> '.png'
            end

            plots = {app.TemperatureAxes, 'temperatures'; ...
                app.HeatFlowAxes,    'heatflows'; ...
                app.RoomAxes,        'room'; ...
                app.InputAxes,       'inputs'};

            % Plots are on different tabs: show each tab while saving it
            originalTab = app.TabGroup.SelectedTab;
            saved = strings(0);
            try
                for k = 1:size(plots, 1)
                    ax = plots{k, 1};
                    app.TabGroup.SelectedTab = ancestor(ax, 'uitab');
                    drawnow;

                    f = fullfile(folder, sprintf('%s_%s%s', base, plots{k, 2}, ext));
                    if strcmpi(ext, '.pdf')
                        exportgraphics(ax, f, 'ContentType', 'vector');
                    else
                        exportgraphics(ax, f, 'Resolution', 300);
                    end
                    saved(end+1) = string(f); %#ok<AGROW>
                end
                app.TabGroup.SelectedTab = originalTab;
                uialert(app.UIFigure, ...
                    sprintf('Saved %d figures:\n%s', numel(saved), strjoin(saved, newline)), ...
                    'Figures saved', 'Icon', 'success');
            catch ME
                app.TabGroup.SelectedTab = originalTab;
                uialert(app.UIFigure, ME.message, 'Saving figures failed');
            end
        end

        % Value changed function: ToProfileDropDown
        function ToProfileDropDownValueChanged(app, event)
            app.UpdateInputs();            
        end

        % Value changed function: QProfileDropDown
        function QProfileDropDownValueChanged(app, event)
            app.UpdateInputs();
        end

        % Button pushed function: RefreshInputGraphsButton
        function RefreshInputGraphsButtonPushed(app, event)
            app.UpdateInputs();
        end

        % Button pushed function: stopsimulationButton
        function stopsimulationButtonPushed(app, event)
            app.StopRequested = true;
        end

        % Button pushed function: ResettoDefaultValuesButton
        function ResettoDefaultValuesButtonPushed(app, event)
            app.C1 = 0.5;
            app.C1EditField.Value = 0.5;
            app.C2 = 1.5;
            app.C2EditField.Value = 1.5;
            app.Rr = 0.5;
            app.RrFieldEditField.Value = 0.5;
            app.Rw = 1.8;
            app.RwFieldEditField.Value = 1.8;
            app.HeaterKnob.Value = 5;
            app.MeanHeaterPower010EditField.Value = 5;
            app.To = 3 ;
            app.ToFieldEditField.Value = 3;
            app.Tr0  = 8; 
            app.InitialRoomTempdegCEditField.Value = 8;
            app.Tw0  = 4;
            app.InitialWallTempdegCEditField.Value = 4;
            app.t_end = 30;
            app.SimulationTimesEditField.Value =30;
            app.Q_amp = 2;
            app.QAmplitudeEditField.Value = 2;
            app.Q_step = 8;
            app.QValueafterstepEditField.Value = 8;
            app.Q_t_prd = 10;
            app.QPeriodsEditField.Value = 10;
            app.Q_t_step = 10;
            app.QSteptimesEditField.Value = 10;
            app.Q_t_hold = 1;
            app.QHoldtimesEditField.Value = 1;
            app.Q_rnd = 1;
            app.QRandomseedEditField.Value =1 ;
            app.To_amp = 2;
            app.ToAmplitudedegCEditField.Value =2;
            app.To_t_prd = 24;
            app.ToPeriodEditField.Value=24;
            app.To_t_hold = 1;
            app.ToHoldtimesEditField.Value=1;
            app.To_rnd = 2;
            app.ToRandomseedEditField.Value=2 ;
            app.ToProfileDropDown.Value = 'Constant';
            app.QProfileDropDown.Value = 'Constant';
            app.ThermostatSwitch.Value = 'Off';
            app.UpdateInputs();
        end

        % Value changed function: MeanHeaterPower010EditField
        function MeanHeaterPower010EditFieldValueChanged(app, event)
            app.HeaterKnob.Value = app.MeanHeaterPower010EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartPage = uitab(app.TabGroup);
            app.StartPage.Title = 'Start Page';
            app.Group2FinalLabel = uilabel(app.StartPage);
            app.Group2FinalLabel.Text = 'Group 2 - Modelling of Thermal Systems';
            app.Label2_5 = uilabel(app.StartPage);
            app.Label2_5.Text = '';
            app.Label2 = uilabel(app.StartPage);
            app.Label2.Text = 'This concise app let''s users model the heat transfer system due to temperature differences caused by the heater in a room. The heater changes the temperature of the room through an automatic or manual mode based on whether the thermostat is ON/OFF.  ';
            app.Label2_2 = uilabel(app.StartPage);
            app.Label2_2.Text = 'Users can customise all the parameters to run this simulation on the Inputs Tab. In this tab, you will also see a visual of the input profile Q(t) and the To(t) in respect to time.';
            app.Label2_3 = uilabel(app.StartPage);
            app.Label2_3.Text = 'In the Animations tab, users can run the simulation to see how the room, wall and outside temperature changes with time.';
            app.Label2_4 = uilabel(app.StartPage);
            app.Label2_4.Text = '                                                                                                                                                                                                      When the thermostat is ON, users simply have to type the desired temperature of the room and press RUN SIMULATION. When the thermostat is OFF, users can visualise the temperature reached with the selected heater power. ';
            app.Inputs = uitab(app.TabGroup);
            app.Inputs.Title = 'Inputs';
            app.InputAxes = uiaxes(app.Inputs);
            title(app.InputAxes, 'Title')
            xlabel(app.InputAxes, 'X')
            ylabel(app.InputAxes, 'Y')
            zlabel(app.InputAxes, 'Z')
            app.MeanOusideTemperaturedegCLabel = uilabel(app.Inputs);
            app.MeanOusideTemperaturedegCLabel.Text = 'Mean Temperature [deg C] ';
            app.ToFieldEditField = uieditfield(app.Inputs, 'numeric');
            app.ToFieldEditField.Value = 3;
            app.Tr0FieldEditFieldLabel = uilabel(app.Inputs);
            app.Tr0FieldEditFieldLabel.Text = 'Initial Room Temp [deg C]';
            app.InitialRoomTempdegCEditField = uieditfield(app.Inputs, 'numeric');
            app.InitialRoomTempdegCEditField.Value = 8;
            app.Tw0FieldEditFieldLabel = uilabel(app.Inputs);
            app.Tw0FieldEditFieldLabel.Text = 'Initial Wall Temp [deg C]';
            app.InitialWallTempdegCEditField = uieditfield(app.Inputs, 'numeric');
            app.InitialWallTempdegCEditField.Value = 4;
            app.SimulationTimesEditFieldLabel = uilabel(app.Inputs);
            app.SimulationTimesEditFieldLabel.Text = 'Simulation Time [s]';
            app.SimulationTimesEditField = uieditfield(app.Inputs, 'numeric');
            app.SimulationTimesEditField.Limits = [0 Inf];
            app.SimulationTimesEditField.Value = 30;
            app.OutsideTemperatureProfileLabel = uilabel(app.Inputs);
            app.OutsideTemperatureProfileLabel.Text = 'Outside Temperature Profile';
            app.ToProfileDropDown = uidropdown(app.Inputs);
            app.ToProfileDropDown.Items = {'Constant', 'Daily sinusoid', 'Random around mean'};
            app.ToProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ToProfileDropDownValueChanged, true);
            app.ToProfileDropDown.Value = 'Constant';
            app.MeanHeaterPowerEditFieldLabel = uilabel(app.Inputs);
            app.MeanHeaterPowerEditFieldLabel.Text = 'Heater Power Level [0-10]';
            app.MeanHeaterPower010EditField = uieditfield(app.Inputs, 'numeric');
            app.MeanHeaterPower010EditField.Limits = [0 10];
            app.MeanHeaterPower010EditField.ValueChangedFcn = createCallbackFcn(app, @MeanHeaterPower010EditFieldValueChanged, true);
            app.MeanHeaterPower010EditField.Value = 5;
            app.HeaterProfileLabel = uilabel(app.Inputs);
            app.HeaterProfileLabel.Text = 'Heater Profile';
            app.QProfileDropDown = uidropdown(app.Inputs);
            app.QProfileDropDown.Items = {'Constant', 'Step', 'Sinusoidal', 'Random'};
            app.QProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @QProfileDropDownValueChanged, true);
            app.QProfileDropDown.Value = 'Constant';
            app.ThermalResistanceRoomWallLabel = uilabel(app.Inputs);
            app.ThermalResistanceRoomWallLabel.Text = 'Thermal Resistance [Room-Wall]';
            app.RrFieldEditField = uieditfield(app.Inputs, 'numeric');
            app.RrFieldEditField.Value = 0.5;
            app.AmplitudedegCEditFieldLabel = uilabel(app.Inputs);
            app.AmplitudedegCEditFieldLabel.Text = 'Amplitude [deg C]';
            app.ToAmplitudedegCEditField = uieditfield(app.Inputs, 'numeric');
            app.ToAmplitudedegCEditField.Limits = [0 Inf];
            app.ToAmplitudedegCEditField.Value = 8;
            app.Period1daysEditFieldLabel = uilabel(app.Inputs);
            app.Period1daysEditFieldLabel.Text = 'Period, 1 day [s]';
            app.ToPeriodEditField = uieditfield(app.Inputs, 'numeric');
            app.ToPeriodEditField.Limits = [0 Inf];
            app.ToPeriodEditField.Value = 8;
            app.RandomseedEditFieldLabel = uilabel(app.Inputs);
            app.RandomseedEditFieldLabel.Text = 'Random seed';
            app.ToRandomseedEditField = uieditfield(app.Inputs, 'numeric');
            app.ToRandomseedEditField.Limits = [0 Inf];
            app.ToRandomseedEditField.Value = 8;
            app.HoldtimesEditField_2Label = uilabel(app.Inputs);
            app.HoldtimesEditField_2Label.Text = 'Hold time [s]';
            app.ToHoldtimesEditField = uieditfield(app.Inputs, 'numeric');
            app.ToHoldtimesEditField.Limits = [0 Inf];
            app.ToHoldtimesEditField.Value = 8;
            app.RefreshInputGraphsButton = uibutton(app.Inputs, 'push');
            app.RefreshInputGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshInputGraphsButtonPushed, true);
            app.RefreshInputGraphsButton.Text = 'Refresh Input Graphs';
            app.C1FieldEditFieldLabel = uilabel(app.Inputs);
            app.C1FieldEditFieldLabel.Text = 'Room Heat Capacity [C1]';
            app.C1EditField = uieditfield(app.Inputs, 'numeric');
            app.C1EditField.Value = 0.5;
            app.Valueafterstep010Label = uilabel(app.Inputs);
            app.Valueafterstep010Label.Text = 'Value after step [0-10]';
            app.QValueafterstepEditField = uieditfield(app.Inputs, 'numeric');
            app.QValueafterstepEditField.Limits = [0 10];
            app.QValueafterstepEditField.Value = 3;
            app.SteptimesEditFieldLabel = uilabel(app.Inputs);
            app.SteptimesEditFieldLabel.Text = 'Step time [s]';
            app.QSteptimesEditField = uieditfield(app.Inputs, 'numeric');
            app.QSteptimesEditField.Limits = [0 Inf];
            app.QSteptimesEditField.Value = 3;
            app.AmplitudeEditFieldLabel = uilabel(app.Inputs);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.QAmplitudeEditField = uieditfield(app.Inputs, 'numeric');
            app.QAmplitudeEditField.Limits = [0 Inf];
            app.QAmplitudeEditField.Value = 3;
            app.PeriodsEditFieldLabel = uilabel(app.Inputs);
            app.PeriodsEditFieldLabel.Text = 'Period [s]';
            app.QPeriodsEditField = uieditfield(app.Inputs, 'numeric');
            app.QPeriodsEditField.Limits = [0 Inf];
            app.QPeriodsEditField.Value = 3;
            app.HoldtimesEditField_3Label = uilabel(app.Inputs);
            app.HoldtimesEditField_3Label.Text = 'Hold time [s]';
            app.QHoldtimesEditField = uieditfield(app.Inputs, 'numeric');
            app.QHoldtimesEditField.Limits = [0 Inf];
            app.QHoldtimesEditField.Value = 3;
            app.RandomseedEditField_2Label = uilabel(app.Inputs);
            app.RandomseedEditField_2Label.Text = 'Random seed';
            app.QRandomseedEditField = uieditfield(app.Inputs, 'numeric');
            app.QRandomseedEditField.Value = 3;
            app.C2FieldEditFieldLabel = uilabel(app.Inputs);
            app.C2FieldEditFieldLabel.Text = 'Wall Heat Capacity [C2]';
            app.C2EditField = uieditfield(app.Inputs, 'numeric');
            app.C2EditField.Value = 1.5;
            app.InitialParametersLabel = uilabel(app.Inputs);
            app.InitialParametersLabel.Text = 'Initial Parameters and Simulation Length';
            app.TimevaryinginputsandLabel_2 = uilabel(app.Inputs);
            app.TimevaryinginputsandLabel_2.Text = 'Outside Temperature Inputs can be specified here:';
            app.TimevaryinginputsandLabel = uilabel(app.Inputs);
            app.TimevaryinginputsandLabel.Text = 'Heater Inputs can be specified here:';
            app.RwFieldEditFieldLabel = uilabel(app.Inputs);
            app.RwFieldEditFieldLabel.Text = 'Thermal Resistance [Wall-Outside]';
            app.RwFieldEditField = uieditfield(app.Inputs, 'numeric');
            app.RwFieldEditField.Value = 1.8;
            app.ResettoDefaultValuesButton = uibutton(app.Inputs, 'push');
            app.ResettoDefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultValuesButtonPushed, true);
            app.ResettoDefaultValuesButton.Text = 'Reset to Default Values';
            app.AnimationsTab = uitab(app.TabGroup);
            app.AnimationsTab.Title = 'Animations';
            app.TemperatureAxes = uiaxes(app.AnimationsTab);
            title(app.TemperatureAxes, 'Temperature curves')
            xlabel(app.TemperatureAxes, 'time')
            ylabel(app.TemperatureAxes, 'temperature')
            zlabel(app.TemperatureAxes, 'Z')
            app.RoomAxes = uiaxes(app.AnimationsTab);
            title(app.RoomAxes, 'Room visualization')
            xlabel(app.RoomAxes, 'X')
            ylabel(app.RoomAxes, 'Y')
            zlabel(app.RoomAxes, 'Z')
            app.HeatFlowAxes = uiaxes(app.AnimationsTab);
            title(app.HeatFlowAxes, 'Heat flows')
            xlabel(app.HeatFlowAxes, 'X')
            ylabel(app.HeatFlowAxes, 'Y')
            zlabel(app.HeatFlowAxes, 'Z')
            app.RunSimulationButton = uibutton(app.AnimationsTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.stopsimulationButton = uibutton(app.AnimationsTab, 'push');
            app.stopsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @stopsimulationButtonPushed, true);
            app.stopsimulationButton.Text = 'Stop Simulation';
            app.ThermostatSwitchLabel = uilabel(app.AnimationsTab);
            app.ThermostatSwitchLabel.Text = 'Thermostat Switch';
            app.ThermostatSwitch = uiswitch(app.AnimationsTab, 'toggle');
            app.ThermostatSwitch.ValueChangedFcn = createCallbackFcn(app, @ThermostatSwitchValueChanged, true);
            app.GaugeLabel = uilabel(app.AnimationsTab);
            app.GaugeLabel.Text = 'Thermostat Temperature Visual';
            app.ThermostattemperatureGauge = uigauge(app.AnimationsTab, 'semicircular');
            app.ThermostattemperatureGauge.Limits = [0 50];
            app.HeaterPowerKnobLabel = uilabel(app.AnimationsTab);
            app.HeaterPowerKnobLabel.Text = 'Heater Power';
            app.HeaterKnob = uiknob(app.AnimationsTab, 'continuous');
            app.HeaterKnob.Limits = [0 10];
            app.HeaterKnob.ValueChangedFcn = createCallbackFcn(app, @HeaterKnobValueChanged, true);
            app.HeaterKnob.Value = 5;
            app.HoldpreviousrunsCheckBox = uicheckbox(app.AnimationsTab);
            app.HoldpreviousrunsCheckBox.Text = 'Hold previous runs';
            app.ExportCSVButton = uibutton(app.AnimationsTab, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButtonPushed, true);
            app.ExportCSVButton.Text = 'Export CSV';
            app.SaveFiguresButton = uibutton(app.AnimationsTab, 'push');
            app.SaveFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFiguresButton.Text = 'Save Figures';
            app.Gauge_2 = uigauge(app.AnimationsTab, 'linear');
            app.Gauge_2.Limits = [0 30];
            app.DesiredroomtemperatureLabel = uilabel(app.AnimationsTab);
            app.DesiredroomtemperatureLabel.Text = 'Desired Room Temperature [deg C]';
            app.DesiredTemperatureEditField = uieditfield(app.AnimationsTab, 'numeric');
            app.DesiredTemperatureEditField.Limits = [-20 50];
            app.DesiredTemperatureEditField.Value = 14.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thermo_sim_sketchv13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end