classdef thermo_sim_sketchv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        Label2                      matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        switchtouserinputButton     matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        Tab2                        matlab.ui.container.Tab
        TimeConstantsTextArea       matlab.ui.control.TextArea
        TimeConstantsTextAreaLabel  matlab.ui.control.Label
        SteadyStateTextArea         matlab.ui.control.TextArea
        SteadyStateTextAreaLabel    matlab.ui.control.Label
        RunSimulationButton         matlab.ui.control.Button
        heaterKnob                  matlab.ui.control.Knob
        heaterKnobLabel             matlab.ui.control.Label
        insidetempKnob              matlab.ui.control.Knob
        insidetempKnobLabel         matlab.ui.control.Label
        outsidetempKnob             matlab.ui.control.Knob
        outsidetempKnobLabel        matlab.ui.control.Label
        CapitanceofroomSlider       matlab.ui.control.Slider
        CapitanceofroomLabel        matlab.ui.control.Label
        UIAxes4                     matlab.ui.control.UIAxes
        TemperatureAxes             matlab.ui.control.UIAxes
        Tab3                        matlab.ui.container.Tab
        ManualOverrideSwitch        matlab.ui.control.ToggleSwitch
        ManualOverrideSwitchLabel   matlab.ui.control.Label
        heaterKnob_2                matlab.ui.control.Knob
        heaterKnob_2Label           matlab.ui.control.Label
        ThermostattemperatureGauge  matlab.ui.control.SemicircularGauge
        Gauge_2Label                matlab.ui.control.Label
        Gauge                       matlab.ui.control.LinearGauge
        stopsimulationButton        matlab.ui.control.Button
        HeatFlowAxes                matlab.ui.control.UIAxes
        RoomAxes                    matlab.ui.control.UIAxes
        Tab4                        matlab.ui.container.Tab
        Switch                      matlab.ui.control.Switch
        SwitchLabel                 matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        LampLabel                   matlab.ui.control.Label
        Tab5                        matlab.ui.container.Tab
        ToProfileDropDown           matlab.ui.control.DropDown
        OutsideToDropDownLabel      matlab.ui.control.Label
        QProfileDropDown            matlab.ui.control.DropDown
        HeaterQDropDownLabel        matlab.ui.control.Label
        TendFieldEditField          matlab.ui.control.NumericEditField
        TendFieldEditFieldLabel     matlab.ui.control.Label
        Tw0FieldEditField           matlab.ui.control.NumericEditField
        Tw0FieldEditFieldLabel      matlab.ui.control.Label
        Tr0FieldEditField           matlab.ui.control.NumericEditField
        Tr0FieldEditFieldLabel      matlab.ui.control.Label
        ToFieldEditField            matlab.ui.control.NumericEditField
        ToFieldEditFieldLabel       matlab.ui.control.Label
        QFieldEditField             matlab.ui.control.NumericEditField
        QFieldEditFieldLabel        matlab.ui.control.Label
        RwFieldEditField            matlab.ui.control.NumericEditField
        RwFieldEditFieldLabel       matlab.ui.control.Label
        RrFieldEditField            matlab.ui.control.NumericEditField
        RrFieldEditFieldLabel       matlab.ui.control.Label
        C2FieldEditField            matlab.ui.control.NumericEditField
        C2FieldEditFieldLabel       matlab.ui.control.Label
        C1FieldEditField            matlab.ui.control.NumericEditField
        C1FieldEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color = '#FF00FF';
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Off"
                app.Lamp.Color = ['#' dec2hex(randi(16777215))];
            else
                app.Lamp.Color = ['#' dec2hex(randi(16777214))];
            end
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            try
                % Read inputs from the app
                C1 = app.C1FieldEditField.Value;
                C2 = app.C2FieldEditField.Value;
                Rr = app.RrFieldEditField.Value;
                Rw = app.RwFieldEditField.Value;
                Q  = app.QFieldEditField.Value;
                To = app.ToFieldEditField.Value;

                Tr0  = app.Tr0FieldEditField.Value;
                Tw0  = app.Tw0FieldEditField.Value;
                Tend = app.TendFieldEditField.Value;

             %check inputs
                if any([C1, C2, Rr, Rw, Tend] <= 0)
                    uialert(app.UIFigure, ...
                        'Heat capacities, resistances and simulation time must be positive.', ...
                        'Invalid input');
                    return
                end

                if Q < 0
                    uialert(app.UIFigure, ...
                        'Heater input must be zero or positive.', ...
                        'Invalid input');
                    return
                end

                % Run the model
               % Heater profile Q(t): level comes from the Q field, the rest is set here
Qcfg.Type   = app.QProfileDropDown.Value;
Qcfg.Level  = Q;       Qcfg.Mean   = Q;
Qcfg.Level2 = Q + 3;   Qcfg.TStep  = Tend/3;    % step
Qcfg.Amp    = 2;       Qcfg.Period = 10;        % sinusoidal / random
Qcfg.Hold   = 1;       Qcfg.Seed   = 1;         % random

Tocfg.Type   = app.ToProfileDropDown.Value;
Tocfg.Level  = To;     Tocfg.Mean  = To;
Tocfg.Amp    = 2;      Tocfg.Period = 24;
Tocfg.Hold   = 1;      Tocfg.Seed  = 2;

out  = ThermalModelTV(C1, C2, Rr, Rw, Tr0, Tw0, Tend, Qcfg, Tocfg);
To_t = out.To;     % outside temperature over time
                %% Prepare simulation results
                t  = out.t;
                Tr = out.Tr;
                Tw = out.Tw;
                
               Q_heat = out.Q;
               Q_rw   = (Tr - Tw) / Rr;
               Q_wo   = (Tw - To_t) / Rw;
                allTemps = [Tr(:); Tw(:); To_t(:); out.Tr_ss; out.Tw_ss];
                Tmin = min(allTemps) - 1;
                Tmax = max(allTemps) + 1;
                
                %% Use the axes already in the app
                ax1 = app.RoomAxes;
                ax2 = app.TemperatureAxes;
                ax3 = app.HeatFlowAxes;
                
                cla(ax1);
                cla(ax2);
                cla(ax3);
                
                %% 1) Room diagram
                hold(ax1, 'on');
                
                zones = {[0 1], [1 2], [2 5]};
                zoneNames = {'Outside', 'Wall', 'Room'};
                
                hP = gobjects(1, 3);
                hT = gobjects(1, 3);
                
                for k = 1:3
                    left  = zones{k}(1);
                    right = zones{k}(2);
                
                    hP(k) = patch(ax1, ...
                        [left right right left], [0 0 3 3], To_t(1), ...
                        'FaceColor', 'flat', ...
                        'EdgeColor', 'k', 'LineWidth', 1.5);
                
                    text(ax1, mean(zones{k}), 2.75, zoneNames{k}, ...
                        'HorizontalAlignment', 'center', ...
                        'FontWeight', 'bold');
                
                    hT(k) = text(ax1, mean(zones{k}), 1.8, '', ...
                        'HorizontalAlignment', 'center', ...
                        'FontSize', 12, 'FontWeight', 'bold', ...
                        'BackgroundColor', [1 1 1]);
                end
                
                % Heater
                patch(ax1, [4.6 4.9 4.9 4.6], [0.3 0.3 1.3 1.3], ...
                    [0.4 0.4 0.4]);
                
                text(ax1, 4.75, 1.45, 'Heater', ...
                    'HorizontalAlignment', 'center', 'FontSize', 9);
                
                colormap(ax1, turbo);
                clim(ax1, [Tmin Tmax]);
                
                cb = colorbar(ax1);
                cb.Label.String = 'Temperature [°C]';
                
                axis(ax1, 'equal');
                axis(ax1, 'off');
                xlim(ax1, [-0.1 5.1]);
                ylim(ax1, [-0.1 3.1]);
                
                hTitle = title(ax1, '');
                hold(ax1, 'off');
                
                %% 2) Temperature curves
                hold(ax2, 'on');
                
                hRoom = plot(ax2, t, Tr, 'r', 'LineWidth', 2);
                hWall = plot(ax2, t, Tw, 'b', 'LineWidth', 2);
                
                hOut = plot(ax2, t, To_t, 'k-.', 'LineWidth', 1.5);
                yline(ax2, out.Tr_ss, 'r:', 'Room steady state');
                yline(ax2, out.Tw_ss, 'b:', 'Wall steady state');
                
                xline(ax2, out.tau(1), '--', ...
                    sprintf('\\tau_1 = %.2f', out.tau(1)), ...
                    'Color', [0.4 0.4 0.4]);
                
                xline(ax2, out.tau(2), '--', ...
                    sprintf('\\tau_2 = %.2f', out.tau(2)), ...
                    'Color', [0.4 0.4 0.4]);
                
                hM2 = xline(ax2, t(1), 'k', 'LineWidth', 1.5);

               legend(ax2, [hRoom hWall hOut], {'Room', 'Wall', 'Outside'}, 'Location', 'best');
                
                xlabel(ax2, 'Time [s]');
                ylabel(ax2, 'Temperature [°C]');
                title(ax2, 'Temperature curves');
                xlim(ax2, [t(1) t(end)]);
                grid(ax2, 'on');
                hold(ax2, 'off');
                
                %% 3) Heat-flow curves
                hold(ax3, 'on');
                
                hHeat = plot(ax3, t, Q_heat, ...
                    'Color', [0.85 0.33 0.1], 'LineWidth', 2);
                
                hRW = plot(ax3, t, Q_rw, ...
                    'Color', [0.5 0 0.5], 'LineWidth', 2);
                
                hWO = plot(ax3, t, Q_wo, ...
                    'Color', [0 0.45 0.74], 'LineWidth', 2);
                
                hM3 = xline(ax3, t(1), 'k', 'LineWidth', 1.5);
                
                legend(ax3, [hHeat hRW hWO], ...
                    {'Heater to room', 'Room to wall', 'Wall to outside'}, ...
                    'Location', 'best');
                
                xlabel(ax3, 'Time [s]');
                ylabel(ax3, 'Heat flow [model units]');
                title(ax3, 'Heat flows');
                xlim(ax3, [t(1) t(end)]);
                grid(ax3, 'on');
                hold(ax3, 'off');
                
                %% 4) Animate the room and time markers
                frames = unique(round(linspace(1, numel(t), 200)));
                
                for i = frames
                    % Stop safely if the app or plots have been closed
                    if ~isvalid(app.UIFigure) || ...
                            ~all(isgraphics(hP)) || ...
                            ~all(isgraphics(hT)) || ...
                            ~isgraphics(hM2) || ~isgraphics(hM3)
                        return
                    end
                
                   Tvals = [To_t(i), Tw(i), Tr(i)];
                
                    for k = 1:3
                        hP(k).CData = Tvals(k);
                        hT(k).String = sprintf('%.1f °C', Tvals(k));
                    end
                
                    hM2.Value = t(i);
                    hM3.Value = t(i);
                    hTitle.String = sprintf('Time = %.2f s', t(i));
                
                    drawnow limitrate
                    pause(0.02);   % Playback delay, independent of simulation time
                end

            catch ME
                uialert(app.UIFigure, ME.message, 'Simulation error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'C_1 = 0.5 J/K (humid / poorly insulated room)')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'temperature')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'C_2 = 1.5 J/K (dry / insulated room)')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'temperature')
            zlabel(app.UIAxes, 'Z')
            app.switchtouserinputButton = uibutton(app.Tab, 'push');
            app.switchtouserinputButton.Text = 'switch to user input';
            app.Label = uilabel(app.Tab);
            app.Label2 = uilabel(app.Tab);
            app.Label2.Text = 'Label2';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.TemperatureAxes = uiaxes(app.Tab2);
            title(app.TemperatureAxes, 'TemperatureAxes')
            xlabel(app.TemperatureAxes, 'time')
            ylabel(app.TemperatureAxes, 'temperature')
            zlabel(app.TemperatureAxes, 'Z')
            app.UIAxes4 = uiaxes(app.Tab2);
            title(app.UIAxes4, 'Room visualization')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.CapitanceofroomLabel = uilabel(app.Tab2);
            app.CapitanceofroomLabel.Text = {'Capitance'; 'of room'};
            app.CapitanceofroomSlider = uislider(app.Tab2);
            app.outsidetempKnobLabel = uilabel(app.Tab2);
            app.outsidetempKnobLabel.Text = 'outside temp';
            app.outsidetempKnob = uiknob(app.Tab2, 'continuous');
            app.outsidetempKnob.Limits = [-20 30];
            app.insidetempKnobLabel = uilabel(app.Tab2);
            app.insidetempKnobLabel.Text = 'inside temp';
            app.insidetempKnob = uiknob(app.Tab2, 'continuous');
            app.insidetempKnob.Limits = [10 30];
            app.insidetempKnob.Value = 10;
            app.heaterKnobLabel = uilabel(app.Tab2);
            app.heaterKnobLabel.Text = 'heater';
            app.heaterKnob = uiknob(app.Tab2, 'continuous');
            app.heaterKnob.Limits = [0 5];
            app.RunSimulationButton = uibutton(app.Tab2, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.SteadyStateTextAreaLabel = uilabel(app.Tab2);
            app.SteadyStateTextAreaLabel.Text = 'Steady State';
            app.SteadyStateTextArea = uitextarea(app.Tab2);
            app.TimeConstantsTextAreaLabel = uilabel(app.Tab2);
            app.TimeConstantsTextAreaLabel.Text = 'Time Constants';
            app.TimeConstantsTextArea = uitextarea(app.Tab2);
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.RoomAxes = uiaxes(app.Tab3);
            title(app.RoomAxes, 'Room visualization')
            xlabel(app.RoomAxes, 'X')
            ylabel(app.RoomAxes, 'Y')
            zlabel(app.RoomAxes, 'Z')
            app.HeatFlowAxes = uiaxes(app.Tab3);
            title(app.HeatFlowAxes, 'Title')
            xlabel(app.HeatFlowAxes, 'X')
            ylabel(app.HeatFlowAxes, 'Y')
            zlabel(app.HeatFlowAxes, 'Z')
            app.stopsimulationButton = uibutton(app.Tab3, 'push');
            app.stopsimulationButton.Text = 'stop simulation';
            app.Gauge = uigauge(app.Tab3, 'linear');
            app.Gauge.Limits = [0 30];
            app.Gauge_2Label = uilabel(app.Tab3);
            app.Gauge_2Label.Text = 'Thermostat temperature';
            app.ThermostattemperatureGauge = uigauge(app.Tab3, 'semicircular');
            app.ThermostattemperatureGauge.Limits = [0 50];
            app.heaterKnob_2Label = uilabel(app.Tab3);
            app.heaterKnob_2Label.Text = 'heater';
            app.heaterKnob_2 = uiknob(app.Tab3, 'continuous');
            app.heaterKnob_2.Limits = [0 5];
            app.ManualOverrideSwitchLabel = uilabel(app.Tab3);
            app.ManualOverrideSwitchLabel.Text = {'Manual'; 'Override'};
            app.ManualOverrideSwitch = uiswitch(app.Tab3, 'toggle');
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.LampLabel = uilabel(app.Tab4);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.Tab4);
            app.SwitchLabel = uilabel(app.Tab4);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.Tab4, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.C1FieldEditFieldLabel = uilabel(app.Tab5);
            app.C1FieldEditFieldLabel.Text = 'C1Field';
            app.C1FieldEditField = uieditfield(app.Tab5, 'numeric');
            app.C1FieldEditField.Value = 0.5;
            app.C2FieldEditFieldLabel = uilabel(app.Tab5);
            app.C2FieldEditFieldLabel.Text = 'C2Field';
            app.C2FieldEditField = uieditfield(app.Tab5, 'numeric');
            app.C2FieldEditField.Value = 1.5;
            app.RrFieldEditFieldLabel = uilabel(app.Tab5);
            app.RrFieldEditFieldLabel.Text = 'RrField';
            app.RrFieldEditField = uieditfield(app.Tab5, 'numeric');
            app.RrFieldEditField.Value = 0.5;
            app.RwFieldEditFieldLabel = uilabel(app.Tab5);
            app.RwFieldEditFieldLabel.Text = 'RwField';
            app.RwFieldEditField = uieditfield(app.Tab5, 'numeric');
            app.RwFieldEditField.Value = 1.8;
            app.QFieldEditFieldLabel = uilabel(app.Tab5);
            app.QFieldEditFieldLabel.Text = 'QField';
            app.QFieldEditField = uieditfield(app.Tab5, 'numeric');
            app.QFieldEditField.Value = 5;
            app.ToFieldEditFieldLabel = uilabel(app.Tab5);
            app.ToFieldEditFieldLabel.Text = 'ToField';
            app.ToFieldEditField = uieditfield(app.Tab5, 'numeric');
            app.ToFieldEditField.Value = 3;
            app.Tr0FieldEditFieldLabel = uilabel(app.Tab5);
            app.Tr0FieldEditFieldLabel.Text = 'Tr0Field';
            app.Tr0FieldEditField = uieditfield(app.Tab5, 'numeric');
            app.Tr0FieldEditField.Value = 8;
            app.Tw0FieldEditFieldLabel = uilabel(app.Tab5);
            app.Tw0FieldEditFieldLabel.Text = 'Tw0Field';
            app.Tw0FieldEditField = uieditfield(app.Tab5, 'numeric');
            app.Tw0FieldEditField.Value = 4;
            app.TendFieldEditFieldLabel = uilabel(app.Tab5);
            app.TendFieldEditFieldLabel.Text = 'TendField';
            app.TendFieldEditField = uieditfield(app.Tab5, 'numeric');
            app.TendFieldEditField.Value = 30;
            app.HeaterQDropDownLabel = uilabel(app.Tab5);
            app.HeaterQDropDownLabel.Text = 'Heater, Q';
            app.QProfileDropDown = uidropdown(app.Tab5);
            app.QProfileDropDown.Items = {'Constant', 'Step', 'Sinusoidal', 'Random'};
            app.QProfileDropDown.Value = 'Constant';
            app.OutsideToDropDownLabel = uilabel(app.Tab5);
            app.OutsideToDropDownLabel.Text = 'Outside, To';
            app.ToProfileDropDown = uidropdown(app.Tab5);
            app.ToProfileDropDown.Items = {'Constant', 'Daily sinusoid', 'Random around mean'};
            app.ToProfileDropDown.Value = 'Constant';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thermo_sim_sketchv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end